classdef CellDensityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AxisSelectionsPanel         matlab.ui.container.Panel
        XAxisDropDownLabel          matlab.ui.control.Label
        XAxisDropDown               matlab.ui.control.DropDown
        YAxisDropDownLabel          matlab.ui.control.Label
        YAxisDropDown               matlab.ui.control.DropDown
        TabGroup                    matlab.ui.container.TabGroup
        PlotTab                     matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        LoglogPlotTab               matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        LiveCellsOnlySwitchLabel    matlab.ui.control.Label
        LiveCellsOnlySwitch         matlab.ui.control.Switch
        MetaDataDropDownLabel       matlab.ui.control.Label
        MetaDataDropDown            matlab.ui.control.DropDown
        LoadDataButton              matlab.ui.control.Button
        GatingOptionsDropDownLabel  matlab.ui.control.Label
        GatingOptionsDropDown       matlab.ui.control.DropDown
        SubmitGatingButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Data % table that holds all quantitative data
        metaData % table that holds all non-numerical data
        x_data % contains the column selected for x-axis
        y_data % contains the column selected for y-axis
        row_index % property that keeps track of which row index will be plotted
        metaCols % column names of the metadata
        
        
    end
    
    methods (Access = private)
        
        % plots data based on most recent dropdown values
        function refreshPlot(app)
            
            % ensures columns to be plotted are up-to-date with dropdown selections 
            app.x_data = app.Data.(app.XAxisDropDown.Value);
            app.y_data = app.Data.(app.YAxisDropDown.Value);
            
            
            % linear scale
            app.UIAxes.Title.String = app.XAxisDropDown.Value + " vs. " + app.YAxisDropDown.Value;
            app.UIAxes.XLabel.String = app.XAxisDropDown.Value;
            app.UIAxes.YLabel.String = app.YAxisDropDown.Value;
            
            scatter(app.UIAxes,app.x_data(app.row_index),app.y_data(app.row_index))
            
            % loglog scale
            app.UIAxes2.Title.String = app.XAxisDropDown.Value + " vs. " + app.YAxisDropDown.Value;
            app.UIAxes2.XLabel.String = app.XAxisDropDown.Value;
            app.UIAxes2.YLabel.String = app.YAxisDropDown.Value;
            
            scatter(app.UIAxes2,app.x_data(app.row_index),app.y_data(app.row_index))
            set(app.UIAxes2,'xscale','log')
            set(app.UIAxes2,'yscale','log')
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test)


        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: LiveCellsOnlySwitch
        function LiveCellsOnlySwitchValueChanged(app, event)
            
            value = app.LiveCellsOnlySwitch.Value;
            if value == "On"              
                app.row_index = app.metaData{:,5} == "live";
            
            else 
                % app.row_index = 1:height(app.Data);
                app.row_index = (app.metaData{:,5} == "live" | app.metaData{:,5} == "not_live");
            end
            
            refreshPlot(app)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            % Loads in Data
            [Density.file_fbm,Density.path_fbm] = uigetfile({'*.*','Select the fbm data file.'});
            DensityRead = readtable(strcat(Density.path_fbm,Density.file_fbm));
            colNames = DensityRead.Properties.VariableNames;
                    
            app.Data = DensityRead;            
            
            % loads axis dropdown options based on column names from DensityRead
            [nr,nc] = size(app.Data);
            for i = 2:nc
                app.XAxisDropDown.Items(i-1) = colNames(i); 
                app.YAxisDropDown.Items(i-1) = colNames(i); 
            end            


            % Loads in MetaData
            [Density.file_metadata,Density.path_metadata] = uigetfile({'*.*','Select the metadata file.'});
            DensityRead = readtable(strcat(Density.path_metadata,Density.file_metadata));
            colNames = DensityRead.Properties.VariableNames;
            
            app.metaData = DensityRead;
            app.metaCols = colNames;

            % loads metadata dropdown options based on DensityRead
            [nr,nc] = size(app.metaData);
            app.MetaDataDropDown.Items(1) = {'None'}; % initialize metadata options
            app.GatingOptionsDropDown.Items(:,:) = [];
            app.GatingOptionsDropDown.Items(1) = {'None'};
            for i = 2:nc
                app.MetaDataDropDown.Items(i) = app.metaCols(i); 
            end
                                    
            % initializes index property
            %app.row_index = 1:height(app.Data);
            app.row_index = true([1,height(app.Data)]); % inializes index logical as all true
            
            
            refreshPlot(app)
        end

        % Value changed function: MetaDataDropDown
        function MetaDataDropDownValueChanged(app, event)
            value = app.MetaDataDropDown.Value;
            
            % clears out the list
            app.GatingOptionsDropDown.Items(:,:) = [];
            
            % loads in all unique options for gating
            [a,b] = unique(app.metaData(:,value),'stable');
            for i = 1:height(a)
                app.GatingOptionsDropDown.Items(i) = a{i,1};
            end
            
        end

        % Button pushed function: SubmitGatingButton
        function SubmitGatingButtonPushed(app, event)
            
            gating = convertCharsToStrings(app.GatingOptionsDropDown.Value);
            col = app.MetaDataDropDown.Value;
                       
            %disp(app.metaData(:,col))
            %disp({gating})
            %disp(gating)
            %disp(app.metaData{:,col} == "Trametinib")
            
            %app.row_index = app.metaData(:,col) == gating;     
            
            
            % disp(app.metaData{:,col} == gating) % THIS WORKSSSS!!!!!!!!!
            
            temp_index = app.metaData{:,col} == gating;
            disp(class(temp_index))
            disp(temp_index)
            disp(class(app.row_index))
            %app.row_index = intersect(temp_index, app.row_index, 'stable');
            
            % app.row_index = app.metaData{:,col} == gating;


%             if value == "On"              
%                 app.row_index = app.metaData{:,5} == "live";
%             
%             else 
%                 app.row_index = 1:height(app.Data);
%             end
            
            refreshPlot(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxisSelectionsPanel = uipanel(app.UIFigure);
            app.AxisSelectionsPanel.Title = 'Axis Selections';
            app.XAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.XAxisDropDownLabel.Text = 'X-Axis';
            app.XAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.XAxisDropDown.Items = {'item1', 'item2', 'item3'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 'item1';
            app.YAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.YAxisDropDownLabel.Text = 'Y-Axis';
            app.YAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.YAxisDropDown.Items = {'item1', 'item2', 'item3'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 'item1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoglogPlotTab = uitab(app.TabGroup);
            app.LoglogPlotTab.Title = 'Loglog Plot';
            app.UIAxes2 = uiaxes(app.LoglogPlotTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LiveCellsOnlySwitchLabel = uilabel(app.UIFigure);
            app.LiveCellsOnlySwitchLabel.Text = 'Live Cells Only?';
            app.LiveCellsOnlySwitch = uiswitch(app.UIFigure, 'slider');
            app.LiveCellsOnlySwitch.ValueChangedFcn = createCallbackFcn(app, @LiveCellsOnlySwitchValueChanged, true);
            app.MetaDataDropDownLabel = uilabel(app.UIFigure);
            app.MetaDataDropDownLabel.Text = 'Meta Data';
            app.MetaDataDropDown = uidropdown(app.UIFigure);
            app.MetaDataDropDown.Items = {'item1'};
            app.MetaDataDropDown.ValueChangedFcn = createCallbackFcn(app, @MetaDataDropDownValueChanged, true);
            app.MetaDataDropDown.Value = 'item1';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.GatingOptionsDropDownLabel = uilabel(app.UIFigure);
            app.GatingOptionsDropDownLabel.Text = 'Gating Options';
            app.GatingOptionsDropDown = uidropdown(app.UIFigure);
            app.GatingOptionsDropDown.Items = {'item1'};
            app.GatingOptionsDropDown.Value = 'item1';
            app.SubmitGatingButton = uibutton(app.UIFigure, 'push');
            app.SubmitGatingButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitGatingButtonPushed, true);
            app.SubmitGatingButton.Text = 'Submit Gating';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellDensityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end